classdef Virtual_MRI_Scanner_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RunAcquisitionButton           matlab.ui.control.Button
        SwapHorizontalVerticalLinesCheckBox  matlab.ui.control.CheckBox
        MatrixWidthEditField           matlab.ui.control.NumericEditField
        MatrixWidthEditFieldLabel      matlab.ui.control.Label
        PointDensityEditField          matlab.ui.control.NumericEditField
        RadialDensityEditField         matlab.ui.control.NumericEditField
        PointDensityEditFieldLabel     matlab.ui.control.Label
        RadialDensityEditFieldLabel    matlab.ui.control.Label
        PointDensityEditField_2        matlab.ui.control.NumericEditField
        LineDensityEditField           matlab.ui.control.NumericEditField
        LineDensityEditFieldLabel      matlab.ui.control.Label
        PointDensityEditField_2Label   matlab.ui.control.Label
        AcquisitionTrajectoryButtonGroup  matlab.ui.container.ButtonGroup
        RadialButton                   matlab.ui.control.RadioButton
        CartesianButton                matlab.ui.control.RadioButton
        CenterPanel                    matlab.ui.container.Panel
        GenerateGraphButton            matlab.ui.control.Button
        PositionEditField              matlab.ui.control.NumericEditField
        PositionEditFieldLabel         matlab.ui.control.Label
        DirectionButtonGroup           matlab.ui.container.ButtonGroup
        VerticalButton                 matlab.ui.control.RadioButton
        HorizontalButton               matlab.ui.control.RadioButton
        DifferencesTextArea            matlab.ui.control.TextArea
        DifferencesTextAreaLabel       matlab.ui.control.Label
        CalcDifferenceButton           matlab.ui.control.Button
        Valuesmustbebetween1andNLabel  matlab.ui.control.Label
        y2EditField                    matlab.ui.control.NumericEditField
        y2EditFieldLabel               matlab.ui.control.Label
        x2EditField                    matlab.ui.control.NumericEditField
        x2EditFieldLabel               matlab.ui.control.Label
        y1EditField                    matlab.ui.control.NumericEditField
        y1EditFieldLabel               matlab.ui.control.Label
        x1EditField                    matlab.ui.control.NumericEditField
        x1EditFieldLabel               matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        ImageAnalysisLabel             matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        RunwithTimeButton              matlab.ui.control.Button
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        Valuemustbebetween0and1Label   matlab.ui.control.Label
        AcquisitionTimeLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        N % N value
        matrixOne
        matrixTwo
        fftOne
        fftTwo
        postFFTOneCartesian
        postFFTOneRadial
        postFFTTwoCartesian
        postFFTTwoRadial
        testDifferenceOne
        testDifferenceTwo
        testDifferenceOneCartesian
        testDifferenceTwoCartesian
        testDifferenceOneRadial
        testDifferenceTwoRadial
    end
methods (Access = private)
    function matrix = generatePhantoms(app, size, ellipses, addRectangle)
        % Generates a phantom matrix based on ellipses and adds a rectangle if needed

        % Create phantom with specified ellipses
        matrix = phantom(ellipses, size);

        % Add a rectangle if the addRectangle flag is true
        if addRectangle == true
            [x, y] = deal(size);
            midX = x / 2;
            deltaX = x / 15;
            leftXBoundary = midX - deltaX;
            rightXBoundary = midX + deltaX;

            midY = y / 2;
            deltaY = y / 3.5;
            topYBoundary = midY - deltaY;
            bottomYBoundary = midY + deltaY;

            for i = 1:x
                for j = 1:y
                    % Ensure current coordinate is within the rectangle's boundaries
                    if (j >= leftXBoundary) && (j <= rightXBoundary) && ...
                       (i >= topYBoundary) && (i <= bottomYBoundary)
                        matrix(i, j) = 1;
                    end
                end
            end
        end

        % Display the generated matrix
        figure;
        imagesc(matrix);
        colormap("gray");
        colorbar;
    end
        
        function kspace = fftTransform(app, matrix, direction, text,  parameters)
    legendText = "";
    if direction == "forward"
        color = "jet";
        fprintf(text);
        kspace = fft2(matrix);
        imageArray = log(1 + abs(fftshift(kspace)));
    else
        color = "gray";
        fprintf(text);
        kspace = ifft2(matrix);
        imageArray = abs(kspace);
    end

    % Cartesian
    if (length(parameters) == 5)
        compartment1 = sprintf("Size: %d", parameters(1));
        compartment2 = sprintf("Width: %d", parameters(2));
        compartment3 = sprintf("Line Points: %d", parameters(3));
        compartment4 = sprintf("Lines: %d", parameters(4));
        compartment5 = sprintf("Swap: %d", parameters(5));
        legendText = compartment1 + newline + compartment2 + newline + compartment3 + newline + compartment4 + newline + compartment5;

    % Radial
    elseif (length(parameters) == 3) 
        compartment1 = sprintf("Size: %d", parameters(1));
        compartment2 = sprintf("Radial Line Points: %d", parameters(2));
        compartment3 = sprintf("Radial Lines: %d", parameters(3));
        legendText = compartment1 + newline + compartment2 + newline + compartment3;
    end

    figure;
    sgtitle(text, 'FontWeight', 'bold');
    imagesc(imageArray);
    if (legendText ~= "")
        annotation('textbox', [.005 .1 0 0],'String', legendText, 'FitBoxToText','on');
    end
    colormap(color);
    colorbar;
end
        
        function reconstruction = cartesianSample(app, matrix, width, pointDensity, lineDensity, swap, acquisitionTime)
    [rows,cols] = size(matrix);
    reconstruction = zeros(rows, cols);

    % Scale the point and line density based on acquisition time
    pointDensity = round(pointDensity * acquisitionTime);
    lineDensity = round(lineDensity * acquisitionTime);

    if swap == true
        rowSpacing   = ceil(cols / pointDensity);
        pointSpacing = ceil(rows / lineDensity);
    else
        rowSpacing   = ceil(rows / lineDensity);
        pointSpacing = ceil(cols / pointDensity);
    end

    for i=1:rowSpacing:width
        for j=1:pointSpacing:width

            if swap == true
                point = matrix(j,i);
            else
                point = matrix(i,j);
            end
            reconstruction(i, j) = point;
        end
    end
end
        
        function reconstruction = radialSample(app, kspace, numRadialLines, numPointsPerLine, acquisitionTime)
    [rows, cols] = size(kspace);
    centerX = rows / 2;
    centerY = cols / 2;
    reconstruction = zeros(rows, cols);

    % Scale the radial lines and points per line based on acquisition time
    numRadialLines = round(numRadialLines * acquisitionTime);
    numPointsPerLine = round(numPointsPerLine * acquisitionTime);

    % Calculate the angle between each radial line
    angleIncrement = 2 * pi / numRadialLines;

    for n = 0:numRadialLines-1
        angle = n * angleIncrement;
        for p = 1:numPointsPerLine
            % Calculate the coordinates of the point on the radial line
            x = round(centerX + (p - numPointsPerLine/2) * cos(angle));
            y = round(centerY + (p - numPointsPerLine/2) * sin(angle));

            % Ensure the coordinates are within the grid boundaries
            if x > 0 && x <= rows && y > 0 && y <= cols
                reconstruction(x, y) = kspace(x, y);
            end
        end
    end
end
        
        function result = isOutOfBounds(app,point, size)
    x = point(1);
    y = point(2);

    result = (x < 1) || (y < 1) || (x > size) || (y > size);
        end
        function result = isOutOfRegion(app,center, point, radius)
    x = center(1);
    y = center(2);

    x1 = point(1);
    y1 = point(2);

    distance = sqrt((x - x1)^2 + (y - y1)^2);
    result = distance > radius;
        end
        function average = calculateAverageSignalIntensityOfRegion(app,matrix, radius, position)
    dimensions = size(matrix);
    length = dimensions(1);
    x = position(1);
    y = position(2);
    
    % Box containing region
    leftBoundary   = x - radius;
    rightBoundary  = x + radius;
    topBoundary    = y - radius;
    bottomBoundary = y + radius;

    % Running sum to calculate average
    sum = 0;
    count = 0;

    % Get SI for all points within the boundary to later calculate the region's average SI
    for i=leftBoundary:rightBoundary
        for j=topBoundary:bottomBoundary
            point = [i, j];
            % Ensure point is within matrix and region boundaries
            if (app.isOutOfRegion(position, point, radius) || app.isOutOfBounds(point, length))
                continue;
            end

            % Increment sum and count to average later (uses abs() to get
            % magnitude of signal intensity, especially for when the matrix
            % has complex numbers
            sum = sum + abs(matrix(i, j));
            count = count + 1;
        end
    end

    average = sum / count;
        end
        function difference = signalIntensityDifference(app, matrix, radius, positionOne, positionTwo)
    averageOfRegionOne = app.calculateAverageSignalIntensityOfRegion(matrix, radius, positionOne);
    averageOfRegionTwo = app.calculateAverageSignalIntensityOfRegion(matrix, radius, positionTwo);

    % Calculate difference
    if averageOfRegionOne > averageOfRegionTwo
        difference = averageOfRegionOne - averageOfRegionTwo;
    else
        difference = averageOfRegionTwo - averageOfRegionOne;
    end
end
        
        % Signal Intensity Profiles
% Returns an signal intensity array and other similar data to be plotted for a given matrix
function [signalIntensities, minSI, maxSI, xLabel, titleText] = getSignalIntensities(app,matrix, direction, position)
    [x, y] = size(matrix);
    signalIntensities = zeros(x);
    minSI = inf;
    maxSI = -inf;

    if (direction == "horizontal")
        xLabel = "x Position";
        titleText = "Row " + position + " Profile";

        for i=1:x
            signalIntensity = abs(matrix(position, i));
            signalIntensities(i) = signalIntensity;
            
            % Set min/maxSI accordingly
            if (signalIntensity < minSI)
                minSI = signalIntensity;
            end
            if (signalIntensity > maxSI)
                maxSI = signalIntensity;
            end
        end
    elseif (direction == "vertical")
        xLabel = "y Position";
        titleText = "Column " + position + " Profile";

        for i=1:y
            signalIntensity = abs(matrix(i, position));
            signalIntensities(i) = signalIntensity;

            % Set min/maxSI accordingly
            if (signalIntensity < minSI)
                minSI = signalIntensity;
            end
            if (signalIntensity > maxSI)
                maxSI = signalIntensity;
            end
        end
    end

    range = maxSI - minSI;
    minSI = minSI - (range * .1);
    maxSI = maxSI + (range * .1);
    if minSI == maxSI
        minSI = maxSI - 1;
    end
end
        
        % Generates a pair of plots plotting SI vs Position
function signalIntensityVsPositionGraph(app,matrixOne, matrixTwo, direction, position, type)
    [x1, y1] = size(matrixOne);
    [x2, y2] = size(matrixTwo);

    [signalIntensitiesOne, minSIOne, maxSIOne, xLabelOne, titleTextOne] = app.getSignalIntensities(matrixOne, direction, position);
    [signalIntensitiesTwo, minSITwo, maxSITwo, xLabelTwo, titleTextTwo] = app.getSignalIntensities(matrixTwo, direction, position);

    % Plot figure
    figure;
    sgtitle(type + " - " + titleTextOne);
    subplot(1, 2, 1); plot(signalIntensitiesOne); xlabel(xLabelOne); ylabel("Signal Intensity"); ylim([minSIOne maxSIOne]); xlim([0 x1]); title("Original");
    subplot(1, 2, 2); plot(signalIntensitiesTwo); xlabel(xLabelTwo); ylabel("Signal Intensity"); ylim([minSITwo maxSITwo]); xlim([0 x2]); title("Output");
end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    prompt = {'Enter the size of the matrix (N):'};
    dlgtitle = 'Input Matrix Size';
    dims = [1 35]; % Dimensions of the input dialog box
    definput = {'256'}; % Default value for N
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    % Check if user provided input
    if isempty(answer)
        % User cancelled the dialog; provide a default value or stop execution
        warning("No input provided for N. Defaulting to N = 256.");
        app.N = 256;
    else
        % Convert the input to a number
        app.N = str2double(answer{1});
        % Validate the input
        if isnan(app.N) || app.N <= 0 || mod(app.N, 1) ~= 0
            errordlg('Invalid input for N. Please enter a positive integer.', 'Input Error');
            error('Startup interrupted: Invalid value for N.');
        end
    end
    
    app.LineDensityEditField.Visible = true;
    app.LineDensityEditFieldLabel.Visible = true;

    app.PointDensityEditField.Visible = true;
    app.PointDensityEditFieldLabel.Visible = true;

    app.MatrixWidthEditField.Visible = true;
    app.MatrixWidthEditFieldLabel.Visible = true;

    app.SwapHorizontalVerticalLinesCheckBox.Visible = true;

    app.RadialDensityEditField.Visible = false;
    app.RadialDensityEditFieldLabel.Visible = false;

    app.PointDensityEditField_2.Visible = false; 
    app.PointDensityEditField_2Label.Visible = false;

    
    % Phantom configurations
    phantomOne = [0.5 0.75 0.75 0 0 0];   % Outer circle
    phantomTwo = [0.5 0.75  0.75   0    0 0; % Outer circle
                  1.0 0.20  0.20   0.5  0 0; % Rightmost inner circle
                  1.0 0.15  0.15   0.1  0 0; % Second from right
                  1.0 0.10  0.10  -0.2  0 0; % Third from right
                  1.0 0.05  0.05  -0.4  0 0; % Fourth from right
                  1.0 0.025 0.025 -0.52 0 0]; % Leftmost inner circle

    % Generate phantoms
    disp("Generating Phantom One...");
    app.matrixOne = app.generatePhantoms(app.N, phantomOne, true);
    title("Phantom One")

    disp("Generating Phantom Two...");
    app.matrixTwo = app.generatePhantoms(app.N, phantomTwo, false);
    title("Phantom Two")

    % Get FFT images for both phantoms
    app.fftOne = app.fftTransform(app.matrixOne, "forward", "Phantom 1 Forward FFT","");
    %title("Phantom 1 Forward FFT")
    app.fftTwo = app.fftTransform(app.matrixTwo, "forward", "Phantom 2 Forward FFT ","");
    %title("Phantom 2 Forward FFT")

    % Run FFT inverse to show that it all works correctly
    fftOneReverse = app.fftTransform(app.fftOne, "inverse", "Phantom 1 Inverse FFT","");
    %title("Phantom 1 Inverse FFT")
    fftTwoReverse = app.fftTransform(app.fftTwo, "inverse", "Phantom 2 Inverse FFT","");
    %title("Phantom 2 Inverse FFT")


        end

        % Selection changed function: AcquisitionTrajectoryButtonGroup
        function AcquisitionTrajectoryButtonGroupSelectionChanged(app, event)
    selectedButton = app.AcquisitionTrajectoryButtonGroup.SelectedObject; % Get the selected button

    if selectedButton == app.CartesianButton
        % Show Cartesian-specific components
        app.LineDensityEditField.Visible = true;
        app.LineDensityEditFieldLabel.Visible = true;

        app.PointDensityEditField.Visible = true;
        app.PointDensityEditFieldLabel.Visible = true;

        app.MatrixWidthEditField.Visible = true;
        app.MatrixWidthEditFieldLabel.Visible = true;

        app.SwapHorizontalVerticalLinesCheckBox.Visible = true;

        % Hide Radial-specific components
        app.RadialDensityEditField.Visible = false;
        app.RadialDensityEditFieldLabel.Visible = false;

        app.PointDensityEditField_2.Visible = false; 
        app.PointDensityEditField_2Label.Visible = false;
    else
        % Show Radial-specific components
        app.RadialDensityEditField.Visible = true;
        app.RadialDensityEditFieldLabel.Visible = true;

        app.PointDensityEditField_2.Visible = true; 
        app.PointDensityEditField_2Label.Visible = true;

        % Hide Cartesian-specific components
        app.LineDensityEditField.Visible = false;
        app.LineDensityEditFieldLabel.Visible = false;

        app.PointDensityEditField.Visible = false;
        app.PointDensityEditFieldLabel.Visible = false;

        app.MatrixWidthEditField.Visible = false;
        app.MatrixWidthEditFieldLabel.Visible = false;

        app.SwapHorizontalVerticalLinesCheckBox.Visible = false;
    end
        end

        % Button pushed function: RunAcquisitionButton
        function RunAcquisitionButtonPushed(app, event)
    % Check the selected acquisition trajectory
    % Retrieve acquisition time from a UI component or set a default value
    acquisitionTime = 1; % Default value, or retrieve from a UI component if applicable
    if app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.CartesianButton
    matrixWidth = app.MatrixWidthEditField.Value;
    linePointDensity = app.PointDensityEditField.Value;
    horizontalLineDensity = app.LineDensityEditField.Value;
    swapHorizontalAndVerticalLines = app.SwapHorizontalVerticalLinesCheckBox.Value;

    % Parameters to show in reconstruction
    cartesianInputParameters = [app.N, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines];


    % Call the cartesianSample function with acquisition time
    reconstructionOneCartesian = app.cartesianSample(app.fftOne, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines, acquisitionTime);
    app.postFFTOneCartesian = app.fftTransform(reconstructionOneCartesian, "inverse", "Cartesian - Phantom 1 Inverse FFT",cartesianInputParameters);
    %title("Cartesian - Phantom 1 Inverse FFT");

    reconstructionTwoCartesian = app.cartesianSample(app.fftTwo, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines, acquisitionTime);
    app.postFFTTwoCartesian = app.fftTransform(reconstructionTwoCartesian, "inverse", "Cartesian - Phantom 2 Inverse FFT",cartesianInputParameters);
    %title("Cartesian - Phantom 2 Inverse FFT");

    elseif app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.RadialButton
        numRadialLines = app.RadialDensityEditField.Value;
        numPointsPerLine = app.PointDensityEditField_2.Value;
        % Parameters to show in reconstruction
        radialInputParameters = [app.N, numPointsPerLine, numRadialLines];

        reconstructionOneRadial = app.radialSample(app.fftOne, numRadialLines, numPointsPerLine,acquisitionTime);
        app.postFFTOneRadial = app.fftTransform(reconstructionOneRadial, "inverse", "Radial - Phantom 1 Inverse FFT",radialInputParameters);
        %title("Radial - Phantom 1 Inverse FFT")

        reconstructionTwoRadial = app.radialSample(app.fftTwo, numRadialLines, numPointsPerLine,acquisitionTime);
        app.postFFTTwoRadial = app.fftTransform(reconstructionTwoRadial, "inverse", "Radial - Phantom 2 Inverse FFT",radialInputParameters);
        %title("Radial - Phantom 2 Inverse FFT")

    end

        end

        % Value changed function: MatrixWidthEditField
        function MatrixWidthEditFieldValueChanged(app, event)

        end

        % Button pushed function: CalcDifferenceButton
        function CalcDifferenceButtonPushed(app, event)
    % Grab variables
    radius = app.RadiusEditField.Value;
    x1 = app.x1EditField.Value;
    y1 = app.y1EditField.Value;
    x2 = app.x2EditField.Value;
    y2 = app.y2EditField.Value; 

    % Calculate signal intensity differences based on the selected acquisition trajectory
    if app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.CartesianButton
        app.testDifferenceOne = app.signalIntensityDifference(app.matrixOne, radius, [x1, y1], [x2, y2]);
        app.testDifferenceOneCartesian = app.signalIntensityDifference(app.postFFTOneCartesian, radius, [x1, y1], [x2, y2]);

        app.testDifferenceTwo = app.signalIntensityDifference(app.matrixTwo, radius, [x1, y1], [x2, y2]);
        app.testDifferenceTwoCartesian = app.signalIntensityDifference(app.postFFTTwoCartesian, radius, [x1, y1], [x2, y2]);

        % Format the results for Cartesian with full precision
        resultsText = sprintf('Test Difference One: %f\nTest Difference One Cartesian: %f\nTest Difference Two: %f\nTest Difference Two Cartesian: %f', ...
                              app.testDifferenceOne, app.testDifferenceOneCartesian, app.testDifferenceTwo, app.testDifferenceTwoCartesian);
    else
        app.testDifferenceOne = app.signalIntensityDifference(app.matrixOne, radius, [x1, y1], [x2, y2]);
        app.testDifferenceOneRadial = app.signalIntensityDifference(app.postFFTOneRadial, radius, [x1, y1], [x2, y2]);

        app.testDifferenceTwo = app.signalIntensityDifference(app.matrixTwo, radius, [x1, y1], [x2, y2]);
        app.testDifferenceTwoRadial = app.signalIntensityDifference(app.postFFTTwoRadial, radius, [x1, y1], [x2, y2]);

        % Format the results for Radial with full precision
        resultsText = sprintf('Test Difference One: %f\nTest Difference One Radial: %f\nTest Difference Two: %f\nTest Difference Two Radial: %f', ...
                              app.testDifferenceOne, app.testDifferenceOneRadial, app.testDifferenceTwo, app.testDifferenceTwoRadial);
    end

    % Update the TextArea with the results
    app.DifferencesTextArea.Value = resultsText;
        end

        % Value changed function: DifferencesTextArea
        function DifferencesTextAreaValueChanged(app, event)

        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            position = app.PositionEditField.Value;
            if app.VerticalButton.Value
            direction = 'vertical';
            else
            direction = 'horizontal';
            end

            if app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.CartesianButton
                app.signalIntensityVsPositionGraph(app.matrixOne, app.postFFTOneCartesian, direction, position, "Phantom One - Cartesian");
                app.signalIntensityVsPositionGraph(app.matrixTwo, app.postFFTTwoCartesian, direction, position, "Phantom Two - Cartesian");
            else
                app.signalIntensityVsPositionGraph(app.matrixOne, app.postFFTOneRadial, direction, position, "Phantom One - Radial");
                app.signalIntensityVsPositionGraph(app.matrixTwo, app.postFFTTwoRadial, direction, position, "Phantom Two - Radial");
            end
        end

        % Button pushed function: RunwithTimeButton
        function RunwithTimeButtonPushed(app, event)
            acquisitionTime = app.TimeEditField.Value;
            if app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.CartesianButton
            matrixWidth = app.MatrixWidthEditField.Value;
            linePointDensity = app.PointDensityEditField.Value;
            horizontalLineDensity = app.LineDensityEditField.Value;
            swapHorizontalAndVerticalLines = app.SwapHorizontalVerticalLinesCheckBox.Value;

            % Parameters to show in reconstruction
            cartesianInputParameters = [app.N, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines];


    % Call the cartesianSample function with acquisition time
    reconstructionOneCartesian = app.cartesianSample(app.fftOne, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines, acquisitionTime);
    app.postFFTOneCartesian = app.fftTransform(reconstructionOneCartesian, "inverse", "Cartesian - Phantom 1 Inverse FFT",cartesianInputParameters);
    %title("Cartesian - Phantom 1 Inverse FFT");

    reconstructionTwoCartesian = app.cartesianSample(app.fftTwo, matrixWidth, linePointDensity, horizontalLineDensity, swapHorizontalAndVerticalLines, acquisitionTime);
    app.postFFTTwoCartesian = app.fftTransform(reconstructionTwoCartesian, "inverse", "Cartesian - Phantom 2 Inverse FFT",cartesianInputParameters);
    %title("Cartesian - Phantom 2 Inverse FFT");

    elseif app.AcquisitionTrajectoryButtonGroup.SelectedObject == app.RadialButton
        numRadialLines = app.RadialDensityEditField.Value;
        numPointsPerLine = app.PointDensityEditField_2.Value;
        % Parameters to show in reconstruction
        radialInputParameters = [app.N, numPointsPerLine, numRadialLines];

        reconstructionOneRadial = app.radialSample(app.fftOne, numRadialLines, numPointsPerLine,acquisitionTime);
        app.postFFTOneRadial = app.fftTransform(reconstructionOneRadial, "inverse", "Radial - Phantom 1 Inverse FFT",radialInputParameters);
        %title("Radial - Phantom 1 Inverse FFT")

        reconstructionTwoRadial = app.radialSample(app.fftTwo, numRadialLines, numPointsPerLine,acquisitionTime);
        app.postFFTTwoRadial = app.fftTransform(reconstructionTwoRadial, "inverse", "Radial - Phantom 2 Inverse FFT",radialInputParameters);
        %title("Radial - Phantom 2 Inverse FFT")
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AcquisitionTrajectoryButtonGroup = uibuttongroup(app.LeftPanel);
            app.AcquisitionTrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionTrajectoryButtonGroupSelectionChanged, true);
            app.AcquisitionTrajectoryButtonGroup.Title = 'Acquisition Trajectory';
            app.CartesianButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.CartesianButton.Text = 'Cartesian';
            app.CartesianButton.Value = true;
            app.RadialButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.RadialButton.Text = 'Radial';
            app.PointDensityEditField_2Label = uilabel(app.LeftPanel);
            app.PointDensityEditField_2Label.Text = 'Point Density';
            app.LineDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.LineDensityEditFieldLabel.Text = 'Line Density';
            app.LineDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LineDensityEditField.Limits = [0 Inf];
            app.LineDensityEditField.ValueDisplayFormat = '%.0f';
            app.LineDensityEditField.Tag = 'cartesianLineDensity';
            app.LineDensityEditField.Placeholder = '100';
            app.PointDensityEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.PointDensityEditField_2.Limits = [0 Inf];
            app.PointDensityEditField_2.ValueDisplayFormat = '%.0f';
            app.RadialDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.RadialDensityEditFieldLabel.Text = 'Radial Density';
            app.PointDensityEditFieldLabel = uilabel(app.LeftPanel);
            app.PointDensityEditFieldLabel.Text = 'Point Density';
            app.RadialDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadialDensityEditField.Limits = [0 Inf];
            app.RadialDensityEditField.ValueDisplayFormat = '%.0f';
            app.PointDensityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointDensityEditField.Limits = [0 Inf];
            app.PointDensityEditField.ValueDisplayFormat = '%.0f';
            app.PointDensityEditField.Tag = 'cartesianPointDensity';
            app.PointDensityEditField.Placeholder = '100';
            app.MatrixWidthEditFieldLabel = uilabel(app.LeftPanel);
            app.MatrixWidthEditFieldLabel.Text = 'Matrix Width';
            app.MatrixWidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MatrixWidthEditField.Limits = [0 Inf];
            app.MatrixWidthEditField.ValueDisplayFormat = '%.0f';
            app.MatrixWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixWidthEditFieldValueChanged, true);
            app.MatrixWidthEditField.Tag = 'cartesianMatrixWidth';
            app.MatrixWidthEditField.Placeholder = '100';
            app.SwapHorizontalVerticalLinesCheckBox = uicheckbox(app.LeftPanel);
            app.SwapHorizontalVerticalLinesCheckBox.Tag = 'cartesianSwapLines';
            app.SwapHorizontalVerticalLinesCheckBox.Text = 'Swap Horizontal / Vertical Lines';
            app.RunAcquisitionButton = uibutton(app.LeftPanel, 'push');
            app.RunAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @RunAcquisitionButtonPushed, true);
            app.RunAcquisitionButton.Text = 'Run Acquisition';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ImageAnalysisLabel = uilabel(app.CenterPanel);
            app.ImageAnalysisLabel.Text = 'Image Analysis';
            app.RadiusEditFieldLabel = uilabel(app.CenterPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.CenterPanel, 'numeric');
            app.x1EditFieldLabel = uilabel(app.CenterPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.CenterPanel, 'numeric');
            app.y1EditFieldLabel = uilabel(app.CenterPanel);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.CenterPanel, 'numeric');
            app.x2EditFieldLabel = uilabel(app.CenterPanel);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.CenterPanel, 'numeric');
            app.y2EditFieldLabel = uilabel(app.CenterPanel);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.CenterPanel, 'numeric');
            app.Valuesmustbebetween1andNLabel = uilabel(app.CenterPanel);
            app.Valuesmustbebetween1andNLabel.Text = 'Values must be between 1 and N';
            app.CalcDifferenceButton = uibutton(app.CenterPanel, 'push');
            app.CalcDifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CalcDifferenceButtonPushed, true);
            app.CalcDifferenceButton.Text = 'Calc Difference';
            app.DifferencesTextAreaLabel = uilabel(app.CenterPanel);
            app.DifferencesTextAreaLabel.Text = 'Differences';
            app.DifferencesTextArea = uitextarea(app.CenterPanel);
            app.DifferencesTextArea.ValueChangedFcn = createCallbackFcn(app, @DifferencesTextAreaValueChanged, true);
            app.DifferencesTextArea.Editable = 'off';
            app.DirectionButtonGroup = uibuttongroup(app.CenterPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.HorizontalButton = uiradiobutton(app.DirectionButtonGroup);
            app.HorizontalButton.Text = 'Horizontal';
            app.HorizontalButton.Value = true;
            app.VerticalButton = uiradiobutton(app.DirectionButtonGroup);
            app.VerticalButton.Text = 'Vertical';
            app.PositionEditFieldLabel = uilabel(app.CenterPanel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.CenterPanel, 'numeric');
            app.GenerateGraphButton = uibutton(app.CenterPanel, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.RightPanel = uipanel(app.GridLayout);
            app.AcquisitionTimeLabel = uilabel(app.RightPanel);
            app.AcquisitionTimeLabel.Text = 'Acquisition Time';
            app.Valuemustbebetween0and1Label = uilabel(app.RightPanel);
            app.Valuemustbebetween0and1Label.Text = 'Value must be between 0 and 1';
            app.TimeEditFieldLabel = uilabel(app.RightPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.RightPanel, 'numeric');
            app.RunwithTimeButton = uibutton(app.RightPanel, 'push');
            app.RunwithTimeButton.ButtonPushedFcn = createCallbackFcn(app, @RunwithTimeButtonPushed, true);
            app.RunwithTimeButton.Text = 'Run with Time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Virtual_MRI_Scanner_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end